classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        LeftPanel         matlab.ui.container.Panel
        HexDisp_2         matlab.ui.control.CheckBox
        TextSend          matlab.ui.control.TextArea
        ButtonSend        matlab.ui.control.Button
        ButtonClrSend     matlab.ui.control.Button
        ButtonClrReceive  matlab.ui.control.Button
        TextArea          matlab.ui.control.TextArea
        SerialState       matlab.ui.control.Lamp
        SerialSwitch      matlab.ui.control.Switch
        SerialParityBits  matlab.ui.control.DropDown
        DropDown_5Label   matlab.ui.control.Label
        SerialStopBits    matlab.ui.control.DropDown
        DropDown_4Label   matlab.ui.control.Label
        SerialDataBits    matlab.ui.control.DropDown
        Label_2           matlab.ui.control.Label
        SerialBaud        matlab.ui.control.DropDown
        Label             matlab.ui.control.Label
        SerialPortNumber  matlab.ui.control.DropDown
        DropDownLabel     matlab.ui.control.Label
        CenterPanel       matlab.ui.container.Panel
        TabGroup          matlab.ui.container.TabGroup
        Tab               matlab.ui.container.Tab
        ButtonDataRec     matlab.ui.control.StateButton
        UIAxes            matlab.ui.control.UIAxes
        Tab2              matlab.ui.container.Tab
        RightPanel        matlab.ui.container.Panel
        GridLayout2       matlab.ui.container.GridLayout
        CEditField        matlab.ui.control.NumericEditField
        CEditFieldLabel   matlab.ui.control.Label
        BEditField        matlab.ui.control.NumericEditField
        BEditFieldLabel   matlab.ui.control.Label
        AEditField        matlab.ui.control.NumericEditField
        AEditFieldLabel   matlab.ui.control.Label
        Label_3           matlab.ui.control.Label
        ButtonSend_3      matlab.ui.control.Button
        ButtonSend_2      matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    properties (Access = private)%一些成员变量
        SerialObject;
        
        Timer_id;        
        lockinfor_flag;
        Str_Data;
        Hex_Data;
        Mac_Data;
        packet          = struct;
        flag            = 1; 
        dataBuffer      = [];
        
        axes_dataX      =[];
        axes_dataY      =[];

    end

    methods (Access = private)

        
        % 定时器，暂时没有用到
        function timer_init(app)       
            app.Timer_id = timer;
            app.Timer_id.StartDelay = 0;
            app.Timer_id.Period = 1.0;
            app.Timer_id.ExecutionMode = 'fixedSpacing';
            app.Timer_id.TimerFcn = @(~, ~) timer_handler(app);
        end
        function timer_start(app)       
            start(app.Timer_id);
        end
        function timer_stop(app)       
            stop(app.Timer_id);
        end
        function timer_delete(app)    
            delete(app.Timer_id);
        end
        function timer_handler(app)
        end


        function StartInitial(app)%软件内容初始化
            app.SerialPortNumber.Items=serialportlist("all");
            pax = polaraxes(app.Tab2);  % 在Panel中创建极坐标轴
            theta = linspace(0, 2*pi, 100);
            r = sin(2*theta).*cos(2*theta);
            polarplot(pax, theta, r, 'LineWidth', 2);
            title(pax, '极坐标示例');

        end

        function SerialSend(app,data)%串口发送

            % 检查串口状态
            if ~isvalid(app.SerialObject) || ~strcmpi(app.SerialObject.Status, 'open')
                % 如果串口未连接或已关闭，弹出错误对话框
                hexDisplay = '串口未连接或已关闭';
                app.TextArea.Value = [app.TextArea.Value; hexDisplay];
                return;
            end
            
            % 发送数据
            if ~isempty(data)
                try
                    % 将数据转换为 uint8 类型并发送
                    write(app.SerialObject, uint8(data), 'uint8');
                    hexStr = dec2hex(data, 2);
                    hexDisplay = '';
                    if(size(hexStr, 1)>4)
                        for i = 1:4
                        hexDisplay = [hexDisplay, hexStr(i, :), ' '];
                        end
                        hexDisplay = [hexDisplay,'...'];
                    else
                        for i = 1:size(hexStr, 1)
                        hexDisplay = [hexDisplay, hexStr(i, :), ' '];
                        end
                    end
                    hexDisplay = ['TX:',hexDisplay];
                    app.TextArea.Value = [app.TextArea.Value; hexDisplay];
                    TextAreaupdate(app);
                 catch ME
                    hexDisplay = '发送失败：错误';
                    app.TextArea.Value = [app.TextArea.Value; hexDisplay];
                 end
            end
        end

        function TextAreaupdate(app)%更新文本框
             maxLines = 40;
             if numel(app.TextArea.Value) > maxLines
                 app.TextArea.Value = app.TextArea.Value(end - maxLines + 1:end);
             end
             app.TextArea.scroll('bottom');
        end

        function RecdataDeal(app)%接收数据处理
            if(app.ButtonDataRec.Value==1)%绘图
                
                if (app.dataBuffer(3)==5)
                    data = app.dataBuffer(5)*256*256+app.dataBuffer(6)*256+app.dataBuffer(7);
                    if isempty(app.axes_dataX)
                        app.axes_dataX = 1; 
                    else
                        newIndex = app.axes_dataX(end) + 1;
                        app.axes_dataX = [app.axes_dataX, newIndex];
                    end
                    app.axes_dataY = [app.axes_dataY, data];
                    if length(app.axes_dataY) > 100
                        app.axes_dataX = app.axes_dataX(2:end);  
                        app.axes_dataY = app.axes_dataY(2:end);  
                    end
                    plot(app.UIAxes, app.axes_dataX, app.axes_dataY);

                end
                
             end
        end

        
        function startTaskPolling(app)%创建定时任务
            % 初始化任务状态（1→任务1，2→任务2，3→任务3）
            persistent currentTask;
            if isempty(currentTask)
                currentTask = 1;
            end
            
            % 创建/获取定时器
            persistent tmr;
            if isempty(tmr) || ~isvalid(tmr)
                tmr = timer('StartDelay', 0.1, 'TimerFcn', @(~,~) executeNextTask(app));
            end
            
            % 启动定时器（首次运行任务3，100ms后开始）
            start(tmr);
        end
        
        function executeNextTask(app)%定时器轮询任务
            persistent currentTask;
            if isempty(currentTask)
                currentTask = 1;
            end
            
            % 根据当前任务标识执行对应任务
            switch currentTask
                case 1
                    data = [0xAA, 0x02, 0x09,0xB5];   % 唤醒
                    SerialSend(app,data);
                    nextDelay = 0.5;  % 2000ms
                case 2
                    data = [0xAA, 0x02, 0x05,0xB1];   % 测距
                    SerialSend(app,data);
                    nextDelay = 1.0;  % 700ms
                case 3
                    data = [0xAA, 0x02, 0x0A,0xB6];   % 休眠
                    SerialSend(app,data);
                    nextDelay = 1.0;  % 100ms
            end
            
            % 更新下一个任务（循环 1→2→3→1...）
            currentTask = mod(currentTask, 3) + 1;
            
            % 重新配置定时器，以下一个任务的延迟再次启动
            tmr = timerfind('Tag', 'PollingTimer');  % 假设给定时器设置了Tag
            if isempty(tmr)
                tmr = timer('StartDelay', nextDelay, 'TimerFcn', @(~,~) executeNextTask(app), 'Tag', 'PollingTimer');
            else
                stop(tmr);
                set(tmr, 'StartDelay', nextDelay, 'TimerFcn', @(~,~) executeNextTask(app));
            end
            start(tmr);
        end


        function stopTaskPolling(app)
            % 查找并停止所有轮询定时器
            tmrList = timerfind('Tag', 'PollingTimer');
            
            if ~isempty(tmrList)
                for i = 1:length(tmrList)
                    if isvalid(tmrList(i))
                        stop(tmrList(i));
                        delete(tmrList(i));
                    end
                end
                disp('所有轮询任务已停止');
            else
                disp('未找到运行的轮询任务');
            end
            
            % 清理持久变量（需要在各自函数内部进行）
            clear startTaskPolling;  % 这会清理 startTaskPolling 中的持久变量
            clear executeNextTask;   % 这会清理 executeNextTask 中的持久变量
        end

        function serialconfig(app) % 串口config
            app.SerialPortNumber.Items = serialportlist("all");
            
            if(app.SerialSwitch.Value=='打开')
                delete(app.SerialObject);
                app.SerialState.Color=[0,1,0];      
                
                try
                    app.SerialObject=serialport(app.SerialPortNumber.Value, ...
                        str2double(app.SerialBaud.Value), ...
                        "DataBits",str2double(app.SerialDataBits.Value), ...
                        "StopBits",str2double(app.SerialStopBits.Value), ...
                        "Parity",app.SerialParityBits.Value);
                    %configureTerminator(app.SerialObject,"LF");
                    %configureCallback(app.SerialObject,"terminator",@serialcallback);%不要判断结尾符号
                    configureCallback(app.SerialObject, "byte", 1, @myByteCallback);
                    app.packet.recvBuffer    = [];
                    app.packet.recvBuffSize  = 0;
                    app.packet.Serialcount = 0;
                catch
                    msgbox('串口打开失败');
                    app.SerialState.Color=[0.5,0.5,0.5];
                    app.SerialSwitch.Value='关闭';
                    delete(app.SerialObject);
                end
            elseif(app.SerialSwitch.Value=='关闭')
                delete(app.SerialObject);
                app.SerialState.Color=[0.5,0.5,0.5];
            end
            

            function myByteCallback(src,~)
                % 每次收到字节就重置/启动定时器
                persistent tmr;
                if isempty(tmr) || ~isvalid(tmr)
                    tmr = timer('StartDelay', 0.01, 'TimerFcn', @(~,~) frameComplete(src)); % 10ms 超时
                else
                    stop(tmr);
                    start(tmr);
                     bytesAvailable = app.SerialObject.BytesAvailable;
                    if bytesAvailable > 0
                    newData = fread(app.SerialObject, bytesAvailable, 'uint8');
                    app.dataBuffer = [app.dataBuffer; newData];
                    end
                end
            end

            function frameComplete(src)%接收帧解析
                RecdataDeal(app);
                hexStr = dec2hex(app.dataBuffer, 2);
                hexDisplay = '';
                if(size(hexStr, 1)>4)
                    for i = 1:4
                    hexDisplay = [hexDisplay, hexStr(i, :), ' '];
                    end
                    hexDisplay = [hexDisplay,'...'];
                else
                    for i = 1:size(hexStr, 1)
                    hexDisplay = [hexDisplay, hexStr(i, :), ' '];
                    end
                end
                hexDisplay = ['RX:',hexDisplay];
                app.TextArea.Value = [app.TextArea.Value; hexDisplay];
                TextAreaupdate(app);
                app.dataBuffer = []; 
   
                drawnow limitrate;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %起始调用
            StartInitial(app);
        end

        % Value changed function: SerialPortNumber
        function SerialPortNumberValueChanged(app, event)
            serialconfig(app); 
            
        end

        % Drop down opening function: SerialPortNumber
        function SerialPortNumberOpening(app, event)
            serialconfig(app); 
            
        end

        % Value changed function: SerialBaud
        function SerialBaudValueChanged(app, event)
            serialconfig(app); 
            
        end

        % Value changed function: SerialSwitch
        function SerialSwitchValueChanged(app, event)
            serialconfig(app); 
            
        end

        % Button pushed function: ButtonClrSend
        function ButtonClrSendPushed(app, event)
            %清除发送区
            app.TextSend.Value='';
        end

        % Button pushed function: ButtonClrReceive
        function ButtonClrReceivePushed(app, event)
             %清除接收区
            app.TextArea.Value='';
            app.Str_Data='';
            app.Hex_Data='';
        end

        % Button pushed function: ButtonSend
        function ButtonSendPushed(app, event)
            % 获取发送文本框中的数据
            textCells = app.TextSend.Value;
            % 获取数据的行数
            numLines = numel(textCells);
            % 初始化要发送的数据
            data = [];
            % 拼接数据并添加换行符
            for i = 1:numLines
                line = textCells{i};
                % 移除字符串中的空格
                line = strrep(line, ' ', '');
                % 检查是否为有效的十六进制字符串
                if mod(length(line), 2) ~= 0
                    errordlg('十六进制字符串长度必须为偶数！', '错误');
                    return;
                end
                % 将十六进制字符串转换为字节数组
                hexBytes = [];
                for j = 1:2:length(line)
                    hexPair = line(j:j+1);
                    byteVal = hex2dec(hexPair);
                    hexBytes = [hexBytes, byteVal];
                end
                data = [data, hexBytes];
                % 前 n-1 行添加 CRLF 换行符
                if i < numLines
                    data = [data, 13, 10];
                end
            end
            
            SerialSend(app,data);
        end

        % Value changed function: ButtonDataRec
        function ButtonDataRecValueChanged(app, event)
            value = app.ButtonDataRec.Value;
            if (app.SerialSwitch.Value=='关闭')
                app.TextArea.Value = [app.TextArea.Value; 'DisConnect'];
                TextAreaupdate(app);
            else
                if(value == 1)%开始
                    %data = [0x5A, 0xA5, 0xDD];   % 将十六进制值转换为数组
                    %write(app.SerialObject, uint8(data), 'uint8');
                    startTaskPolling(app);
                    app.ButtonDataRec.Text = '结束';
                else
                    stopTaskPolling(app);
                    app.ButtonDataRec.Text = '开始';
                end
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {546, 546, 546};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {546, 546};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {202, '1x', 240};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DropDownLabel = uilabel(app.LeftPanel);
            app.DropDownLabel.Text = '串口号';
            app.SerialPortNumber = uidropdown(app.LeftPanel);
            app.SerialPortNumber.Items = {'COM1', 'COM2', 'COM3', 'COM4'};
            app.SerialPortNumber.DropDownOpeningFcn = createCallbackFcn(app, @SerialPortNumberOpening, true);
            app.SerialPortNumber.ValueChangedFcn = createCallbackFcn(app, @SerialPortNumberValueChanged, true);
            app.SerialPortNumber.Value = 'COM1';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '波特率';
            app.SerialBaud = uidropdown(app.LeftPanel);
            app.SerialBaud.Items = {'9600', '115200', '921600'};
            app.SerialBaud.ValueChangedFcn = createCallbackFcn(app, @SerialBaudValueChanged, true);
            app.SerialBaud.Value = '9600';
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = '数据位';
            app.SerialDataBits = uidropdown(app.LeftPanel);
            app.SerialDataBits.Items = {'8', '7', '6', '5'};
            app.SerialDataBits.Value = '8';
            app.DropDown_4Label = uilabel(app.LeftPanel);
            app.DropDown_4Label.Text = '停止位';
            app.SerialStopBits = uidropdown(app.LeftPanel);
            app.SerialStopBits.Items = {'1', '1.5', '2'};
            app.SerialStopBits.Value = '1';
            app.DropDown_5Label = uilabel(app.LeftPanel);
            app.DropDown_5Label.Text = '校验位';
            app.SerialParityBits = uidropdown(app.LeftPanel);
            app.SerialParityBits.Items = {'none', 'even', 'odd'};
            app.SerialParityBits.Value = 'none';
            app.SerialSwitch = uiswitch(app.LeftPanel, 'slider');
            app.SerialSwitch.Items = {'关闭', '打开'};
            app.SerialSwitch.ValueChangedFcn = createCallbackFcn(app, @SerialSwitchValueChanged, true);
            app.SerialSwitch.Value = '关闭';
            app.SerialState = uilamp(app.LeftPanel);
            app.TextArea = uitextarea(app.LeftPanel);
            app.ButtonClrReceive = uibutton(app.LeftPanel, 'push');
            app.ButtonClrReceive.ButtonPushedFcn = createCallbackFcn(app, @ButtonClrReceivePushed, true);
            app.ButtonClrReceive.Text = '清除接收';
            app.ButtonClrSend = uibutton(app.LeftPanel, 'push');
            app.ButtonClrSend.ButtonPushedFcn = createCallbackFcn(app, @ButtonClrSendPushed, true);
            app.ButtonClrSend.Text = '清除发送';
            app.ButtonSend = uibutton(app.LeftPanel, 'push');
            app.ButtonSend.ButtonPushedFcn = createCallbackFcn(app, @ButtonSendPushed, true);
            app.ButtonSend.Text = '发送';
            app.TextSend = uitextarea(app.LeftPanel);
            app.HexDisp_2 = uicheckbox(app.LeftPanel);
            app.HexDisp_2.Text = 'HEX接收';
            app.HexDisp_2.Value = true;
            app.CenterPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.CenterPanel);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, 'Title')
            app.ButtonDataRec = uibutton(app.Tab, 'state');
            app.ButtonDataRec.ValueChangedFcn = createCallbackFcn(app, @ButtonDataRecValueChanged, true);
            app.ButtonDataRec.Text = '开始';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.ButtonSend_2 = uibutton(app.GridLayout2, 'push');
            app.ButtonSend_2.Text = '上传';
            app.ButtonSend_3 = uibutton(app.GridLayout2, 'push');
            app.ButtonSend_3.Text = '下载';
            app.Label_3 = uilabel(app.GridLayout2);
            app.Label_3.Text = '参数设置';
            app.AEditFieldLabel = uilabel(app.GridLayout2);
            app.AEditFieldLabel.Text = '参数A';
            app.AEditField = uieditfield(app.GridLayout2, 'numeric');
            app.BEditFieldLabel = uilabel(app.GridLayout2);
            app.BEditFieldLabel.Text = '参数B';
            app.BEditField = uieditfield(app.GridLayout2, 'numeric');
            app.CEditFieldLabel = uilabel(app.GridLayout2);
            app.CEditFieldLabel.Text = '参数C';
            app.CEditField = uieditfield(app.GridLayout2, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end